classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThreeTankSystem                 matlab.ui.Figure
        Visualisierungdes3TankSystemsButton  matlab.ui.control.Button
        Gekoppelte3TankSystemPanel      matlab.ui.container.Panel
        VisualisierungButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        GrkemAytekinOulcanYldrmLabel_2  matlab.ui.control.Label
        gekopSchlissen                  matlab.ui.control.Button
        GenerateButton_2                matlab.ui.control.Button
        A3Slider_2                      matlab.ui.control.Slider
        A3Slider_2Label                 matlab.ui.control.Label
        K3Slider_2                      matlab.ui.control.Slider
        K3Slider_2Label                 matlab.ui.control.Label
        A2Slider_2                      matlab.ui.control.Slider
        A2Slider_2Label                 matlab.ui.control.Label
        K2Slider_2                      matlab.ui.control.Slider
        K2Slider_2Label                 matlab.ui.control.Label
        A1Slider_2                      matlab.ui.control.Slider
        A1Slider_2Label                 matlab.ui.control.Label
        K1Slider_2                      matlab.ui.control.Slider
        K1Slider_2Label                 matlab.ui.control.Label
        VentilSlider_2                  matlab.ui.control.Slider
        VentilSlider_2Label             matlab.ui.control.Label
        DurationSlider_2                matlab.ui.control.Slider
        DurationSlider_2Label           matlab.ui.control.Label
        H3Label_2                       matlab.ui.control.Label
        H2Label_2                       matlab.ui.control.Label
        H1Label_2                       matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        TDU                             matlab.ui.control.Label
        MEC424ProduktionLabel           matlab.ui.control.Label
        GrkemAytekinOulcanYldrmLabel    matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        UngekoppelteButton              matlab.ui.control.Button
        GekoppelteButton                matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Ungekoppelte3TankSystemPanel    matlab.ui.container.Panel
        Visualisierung_Ungekoppelt      matlab.ui.control.Button
        VentilSlider                    matlab.ui.control.Slider
        VentilSliderLabel               matlab.ui.control.Label
        DurationSlider                  matlab.ui.control.Slider
        DurationSliderLabel             matlab.ui.control.Label
        A3Slider                        matlab.ui.control.Slider
        A3SliderLabel                   matlab.ui.control.Label
        K3Slider                        matlab.ui.control.Slider
        K3SliderLabel                   matlab.ui.control.Label
        A2Slider                        matlab.ui.control.Slider
        A2SliderLabel                   matlab.ui.control.Label
        K2Slider                        matlab.ui.control.Slider
        K2SliderLabel                   matlab.ui.control.Label
        A1Slider                        matlab.ui.control.Slider
        A1SliderLabel                   matlab.ui.control.Label
        K1Slider                        matlab.ui.control.Slider
        K1SliderLabel                   matlab.ui.control.Label
        H3Label                         matlab.ui.control.Label
        H2Label                         matlab.ui.control.Label
        H1Label                         matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        ungekoppSchlissen               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          load_system("DreiTankSim_Visual.slx")
        end

        % Button pushed function: ungekoppSchlissen
        function ungekoppSchlissenPushed(app, event)
            if strcmpi(get(app.Ungekoppelte3TankSystemPanel,'Visible'),'on')
            set(app.Ungekoppelte3TankSystemPanel,'Visible','off')
            set(app.Image3,'Visible','on')
            set(app.Image4,'Visible','on')
            set(app.GekoppelteButton,'Visible','on')
            set(app.UngekoppelteButton,'Visible','on')
            set(app.TDU,'Visible','on')
            set(app.Image2,'Visible','on')
            set(app.Visualisierungdes3TankSystemsButton,'Visible','on')
            close_system("Ungekoppelt_Plot.slx")
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            assignin("base","Kv",app.VentilSlider.Value);
            assignin("base","k1",app.K1Slider.Value);
            assignin("base","A1",app.A1Slider.Value);
            assignin("base","k2",app.K2Slider.Value);
            assignin("base","A2",app.A2Slider.Value);
            assignin("base","k3",app.K3Slider.Value);
            assignin("base","A3",app.A3Slider.Value);
            app.DurationSlider.Value;
            out = sim("Ungekoppelt_Plot.slx",'StopTime',num2str(app.DurationSlider.Value));
            set(app.UIAxes,'NextPlot','replace')
            plot(app.UIAxes,out.h1Value.Time,out.h1Value.Data,"Color","red")
            set(app.UIAxes,'NextPlot','add')
            set(app.UIAxes,'XGrid','on')
            set(app.UIAxes,'YGrid','on')
            set(app.UIAxes,'BackgroundColor', 'white')
            plot(app.UIAxes,out.h2Value.Time,out.h2Value.Data,"Color","green")   
            plot(app.UIAxes,out.h3Value.Time,out.h3Value.Data,"Color","blue")

        end

        % Button pushed function: UngekoppelteButton
        function UngekoppelteButtonPushed(app, event)
            
            if strcmpi(get(app.Ungekoppelte3TankSystemPanel,'Visible'),'off')
            set(app.Ungekoppelte3TankSystemPanel,'Visible','on')
            set(app.Image3,'Visible','off')
            set(app.Image4,'Visible','off')
            set(app.GekoppelteButton,'Visible','off')
            set(app.UngekoppelteButton,'Visible','off')
            set(app.TDU,'Visible','off')
            set(app.Image2,'Visible','off')
            set(app.Visualisierungdes3TankSystemsButton,'Visible','off')
            load_system("DreiTankSim_Visual.slx")
            load_system("Ungekoppelt_Plot.slx")
            end
            
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            assignin("base","Kv",app.VentilSlider_2.Value);
            assignin("base","k1",app.K1Slider_2.Value);
            assignin("base","A1",app.A1Slider_2.Value);
            assignin("base","k2",app.K2Slider_2.Value);
            assignin("base","A2",app.A2Slider_2.Value);
            assignin("base","k3",app.K3Slider_2.Value);
            assignin("base","A3",app.A3Slider_2.Value);
            out = sim("Gekoppelt_Plot.slx",'StopTime',num2str(app.DurationSlider_2.Value));
            set(app.UIAxes_2,'NextPlot','replace')
            plot(app.UIAxes_2,out.h4Value.Time,out.h4Value.Data,"Color","red")
            set(app.UIAxes_2,'NextPlot','add')
            set(app.UIAxes_2,'XGrid','on')
            set(app.UIAxes_2,'YGrid','on')
            set(app.UIAxes_2,'BackgroundColor', 'white')
            plot(app.UIAxes_2,out.h5Value.Time,out.h5Value.Data,"Color","green")   
            plot(app.UIAxes_2,out.h6Value.Time,out.h6Value.Data,"Color","blue")  
        end

        % Button pushed function: GekoppelteButton
        function GekoppelteButtonPushed(app, event)
            if strcmpi(get(app.Gekoppelte3TankSystemPanel,'Visible'),'off')
            set(app.Gekoppelte3TankSystemPanel,'Visible','on')
            set(app.Image3,'Visible','off')
            set(app.Image4,'Visible','off')
            set(app.GekoppelteButton,'Visible','off')
            set(app.UngekoppelteButton,'Visible','off')
            set(app.TDU,'Visible','off')
            set(app.Image2,'Visible','off')
            set(app.Visualisierungdes3TankSystemsButton,'Visible','off')
            load_system("DreiTankSim_Visual.slx")
            load_system("Gekoppelt_Plot.slx")
            end
        end

        % Button pushed function: gekopSchlissen
        function gekopSchlissenPushed(app, event)
            if strcmpi(get(app.Gekoppelte3TankSystemPanel,'Visible'),'on')
            set(app.Gekoppelte3TankSystemPanel,'Visible','off')
            set(app.Image3,'Visible','on')
            set(app.Image4,'Visible','on')
            set(app.GekoppelteButton,'Visible','on')
            set(app.UngekoppelteButton,'Visible','on')
            set(app.TDU,'Visible','on')
            set(app.Image2,'Visible','on')
            set(app.Visualisierungdes3TankSystemsButton,'Visible','on')
            close_system("Gekoppelt_Plot.slx")
            end
        end

        % Button pushed function: VisualisierungButton
        function VisualisierungButtonPushed(app, event)
            open_system('DreiTankSim_Visual/Gekoppelte_Drei_Tank_System','tab')
        end

        % Button pushed function: Visualisierung_Ungekoppelt
        function Visualisierung_UngekoppeltButtonPushed(app, event)

            open_system('DreiTankSim_Visual/Ungekoppelte_Drei_Tank_System','tab')
        end

        % Button pushed function: 
        % Visualisierungdes3TankSystemsButton
        function Visualisierungdes3TankSystemsButtonPushed(app, event)
            open_system('DreiTankSim_Visual','tab')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThreeTankSystem = uifigure('Visible', 'off');
            app.ThreeTankSystem.Name = 'UI Figure';
            app.Ungekoppelte3TankSystemPanel = uipanel(app.ThreeTankSystem);
            app.Ungekoppelte3TankSystemPanel.Title = 'Ungekoppelte 3 Tank System';
            app.Ungekoppelte3TankSystemPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.Ungekoppelte3TankSystemPanel);
            title(app.UIAxes, {'Wasserabstand/Zeit'; ''})
            xlabel(app.UIAxes, 'Zeit')
            ylabel(app.UIAxes, 'Wasserabstand')
            app.UIAxes.LineStyleOrderIndex = 20;
            app.ungekoppSchlissen = uibutton(app.Ungekoppelte3TankSystemPanel, 'push');
            app.ungekoppSchlissen.ButtonPushedFcn = createCallbackFcn(app, @ungekoppSchlissenPushed, true);
            app.ungekoppSchlissen.Text = 'Zurück';
            app.GenerateButton = uibutton(app.Ungekoppelte3TankSystemPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.H1Label = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.H1Label.Text = 'H1';
            app.H2Label = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.H2Label.Text = 'H2';
            app.H3Label = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.H3Label.Text = 'H3';
            app.K1SliderLabel = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.K1SliderLabel.Text = 'K1';
            app.K1Slider = uislider(app.Ungekoppelte3TankSystemPanel);
            app.K1Slider.Limits = [0 10];
            app.K1Slider.Value = 1.5;
            app.A1SliderLabel = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.A1SliderLabel.Text = 'A1';
            app.A1Slider = uislider(app.Ungekoppelte3TankSystemPanel);
            app.A1Slider.Value = 20;
            app.K2SliderLabel = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.K2SliderLabel.Text = 'K2';
            app.K2Slider = uislider(app.Ungekoppelte3TankSystemPanel);
            app.K2Slider.Limits = [0 10];
            app.K2Slider.Value = 1.5;
            app.A2SliderLabel = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.A2SliderLabel.Text = 'A2';
            app.A2Slider = uislider(app.Ungekoppelte3TankSystemPanel);
            app.A2Slider.Value = 20;
            app.K3SliderLabel = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.K3SliderLabel.Text = 'K3';
            app.K3Slider = uislider(app.Ungekoppelte3TankSystemPanel);
            app.K3Slider.Limits = [0 10];
            app.K3Slider.Value = 1.5;
            app.A3SliderLabel = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.A3SliderLabel.Text = 'A3';
            app.A3Slider = uislider(app.Ungekoppelte3TankSystemPanel);
            app.A3Slider.Value = 20;
            app.DurationSliderLabel = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.DurationSliderLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.Ungekoppelte3TankSystemPanel);
            app.DurationSlider.Limits = [0 200];
            app.VentilSliderLabel = uilabel(app.Ungekoppelte3TankSystemPanel);
            app.VentilSliderLabel.Text = 'Ventil';
            app.VentilSlider = uislider(app.Ungekoppelte3TankSystemPanel);
            app.VentilSlider.Limits = [0 20];
            app.VentilSlider.Value = 10;
            app.Visualisierung_Ungekoppelt = uibutton(app.Ungekoppelte3TankSystemPanel, 'push');
            app.Visualisierung_Ungekoppelt.ButtonPushedFcn = createCallbackFcn(app, @Visualisierung_UngekoppeltButtonPushed, true);
            app.Visualisierung_Ungekoppelt.Text = 'Visualisierung & Animation';
            app.Image2 = uiimage(app.ThreeTankSystem);
            app.Image2.ImageSource = 'Tau Arka Plan.png';
            app.GekoppelteButton = uibutton(app.ThreeTankSystem, 'push');
            app.GekoppelteButton.ButtonPushedFcn = createCallbackFcn(app, @GekoppelteButtonPushed, true);
            app.GekoppelteButton.Text = {'Simulation für '; 'Gekoppelte 3 Tank System'};
            app.UngekoppelteButton = uibutton(app.ThreeTankSystem, 'push');
            app.UngekoppelteButton.ButtonPushedFcn = createCallbackFcn(app, @UngekoppelteButtonPushed, true);
            app.UngekoppelteButton.Text = {'Simulation für'; 'Ungekoppelte 3 Tank System'};
            app.Image3 = uiimage(app.ThreeTankSystem);
            app.Image3.ImageSource = 'ungekoppeltFotoSim.png';
            app.Image4 = uiimage(app.ThreeTankSystem);
            app.Image4.ImageSource = 'GekoppeltSim.png';
            app.GrkemAytekinOulcanYldrmLabel = uilabel(app.ThreeTankSystem);
            app.GrkemAytekinOulcanYldrmLabel.Text = {'Görkem Aytekin'; 'Oğulcan Yıldırım'};
            app.MEC424ProduktionLabel = uilabel(app.ThreeTankSystem);
            app.MEC424ProduktionLabel.Text = 'MEC425 Produktionsautomatisierungsprojekt I Prof. Anatoli Makarov ';
            app.TDU = uilabel(app.ThreeTankSystem);
            app.TDU.Text = {'Türkisch-Deutsche Universität'; '3 Tank System'};
            app.Gekoppelte3TankSystemPanel = uipanel(app.ThreeTankSystem);
            app.Gekoppelte3TankSystemPanel.Title = 'Gekoppelte 3 Tank System';
            app.Gekoppelte3TankSystemPanel.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.Gekoppelte3TankSystemPanel);
            title(app.UIAxes_2, {'Wasserabstand/Zeit'; ''})
            xlabel(app.UIAxes_2, {'Zeit'; ''})
            ylabel(app.UIAxes_2, {'Wasserabstand'; ''; ''})
            app.UIAxes_2.LineStyleOrderIndex = 20;
            app.H1Label_2 = uilabel(app.Gekoppelte3TankSystemPanel);
            app.H1Label_2.Text = 'H1';
            app.H2Label_2 = uilabel(app.Gekoppelte3TankSystemPanel);
            app.H2Label_2.Text = 'H2';
            app.H3Label_2 = uilabel(app.Gekoppelte3TankSystemPanel);
            app.H3Label_2.Text = 'H3';
            app.DurationSlider_2Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.DurationSlider_2Label.Text = 'Duration';
            app.DurationSlider_2 = uislider(app.Gekoppelte3TankSystemPanel);
            app.DurationSlider_2.Limits = [0 200];
            app.VentilSlider_2Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.VentilSlider_2Label.Text = 'Ventil';
            app.VentilSlider_2 = uislider(app.Gekoppelte3TankSystemPanel);
            app.VentilSlider_2.Limits = [0 20];
            app.VentilSlider_2.Value = 10;
            app.K1Slider_2Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.K1Slider_2Label.Text = 'K1';
            app.K1Slider_2 = uislider(app.Gekoppelte3TankSystemPanel);
            app.K1Slider_2.Limits = [0 10];
            app.K1Slider_2.Value = 5;
            app.A1Slider_2Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.A1Slider_2Label.Text = 'A1';
            app.A1Slider_2 = uislider(app.Gekoppelte3TankSystemPanel);
            app.A1Slider_2.Value = 20;
            app.K2Slider_2Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.K2Slider_2Label.Text = 'K2';
            app.K2Slider_2 = uislider(app.Gekoppelte3TankSystemPanel);
            app.K2Slider_2.Limits = [0 10];
            app.K2Slider_2.Value = 5;
            app.A2Slider_2Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.A2Slider_2Label.Text = 'A2';
            app.A2Slider_2 = uislider(app.Gekoppelte3TankSystemPanel);
            app.A2Slider_2.Value = 20;
            app.K3Slider_2Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.K3Slider_2Label.Text = 'K3';
            app.K3Slider_2 = uislider(app.Gekoppelte3TankSystemPanel);
            app.K3Slider_2.Limits = [0 10];
            app.K3Slider_2.Value = 5;
            app.A3Slider_2Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.A3Slider_2Label.Text = 'A3';
            app.A3Slider_2 = uislider(app.Gekoppelte3TankSystemPanel);
            app.A3Slider_2.Value = 20;
            app.GenerateButton_2 = uibutton(app.Gekoppelte3TankSystemPanel, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.gekopSchlissen = uibutton(app.Gekoppelte3TankSystemPanel, 'push');
            app.gekopSchlissen.ButtonPushedFcn = createCallbackFcn(app, @gekopSchlissenPushed, true);
            app.gekopSchlissen.Text = 'Zurück';
            app.GrkemAytekinOulcanYldrmLabel_2 = uilabel(app.Gekoppelte3TankSystemPanel);
            app.GrkemAytekinOulcanYldrmLabel_2.Text = {'Görkem Aytekin'; 'Oğulcan Yıldırım'};
            app.Label = uilabel(app.Gekoppelte3TankSystemPanel);
            app.Label.Text = 'MEC425 Produktionsautomatisierungsprojekt I Prof. Anatoli Makarov ';
            app.VisualisierungButton = uibutton(app.Gekoppelte3TankSystemPanel, 'push');
            app.VisualisierungButton.ButtonPushedFcn = createCallbackFcn(app, @VisualisierungButtonPushed, true);
            app.VisualisierungButton.Text = 'Visualisierung';
            app.Visualisierungdes3TankSystemsButton = uibutton(app.ThreeTankSystem, 'push');
            app.Visualisierungdes3TankSystemsButton.ButtonPushedFcn = createCallbackFcn(app, @Visualisierungdes3TankSystemsButtonPushed, true);
            app.Visualisierungdes3TankSystemsButton.Text = 'Visualisierung des 3 Tank Systems';
            % Show the figure after all components are created
            app.ThreeTankSystem.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThreeTankSystem)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThreeTankSystem)
        end
    end
end